classdef GUI_annotations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIAutomaticManualAnnotationsUIFigure  matlab.ui.Figure
        RightPanel                     matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        annot                          matlab.ui.control.UIAxes
        ParametricmapsPanel            matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        CBF                            matlab.ui.control.Image
        CBV                            matlab.ui.control.Image
        CBFLabel                       matlab.ui.control.Label
        CBVLabel                       matlab.ui.control.Label
        TTP                            matlab.ui.control.Image
        TTPLabel                       matlab.ui.control.Label
        TMAX                           matlab.ui.control.Image
        MIP                            matlab.ui.control.Image
        MIPLabel                       matlab.ui.control.Label
        TMAXLabel                      matlab.ui.control.Label
        MTT                            matlab.ui.control.Image
        MTTLabel                       matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ParametricmapsTab              matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        DICOMimagesmainfolderPanel     matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        RawdataField                   matlab.ui.control.EditField
        BrowseButton_3                 matlab.ui.control.Button
        MainSavefolderPanel_2          matlab.ui.container.Panel
        GridLayout13_2                 matlab.ui.container.GridLayout
        savepatientField               matlab.ui.control.EditField
        BrowseButton_4                 matlab.ui.control.Button
        ExtractParametricMapsButton    matlab.ui.control.Button
        PredictionsTab                 matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        RunallpatientsCheckBox         matlab.ui.control.CheckBox
        RunButton                      matlab.ui.control.Button
        FrompatientSpinnerLabel        matlab.ui.control.Label
        FrompatientSpinner             matlab.ui.control.Spinner
        TopatientSpinnerLabel          matlab.ui.control.Label
        TopatientSpinner               matlab.ui.control.Spinner
        MainSavefolderPanel            matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        PatientFolderField             matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        ModelFolderPanel               matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        ModelFolderField               matlab.ui.control.EditField
        BrowseButton_2                 matlab.ui.control.Button
        FinalizeParametricMapsButton   matlab.ui.control.Button
        DrawTab                        matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        SavecurrentworkButton          matlab.ui.control.Button
        DrawnewannotationButton        matlab.ui.control.Button
        DeleteselectedButton           matlab.ui.control.Button
        ListAnnotations                matlab.ui.control.ListBox
        chooseRegion                   matlab.ui.control.DropDown
        VisualizeannotationsLabel      matlab.ui.control.Label
        LoadannotationsfromslicePanel  matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        PreviousButton_4               matlab.ui.control.Button
        NextButton_4                   matlab.ui.control.Button
        ConfirminadvanceCheckBox       matlab.ui.control.CheckBox
        LoadannotationsPanel           matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        AutomaticOriginalButton        matlab.ui.control.Button
        ManualButton                   matlab.ui.control.Button
        PatientfolderinfoPanel         matlab.ui.container.Panel
        GridLayout16                   matlab.ui.container.GridLayout
        PreviousButton                 matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        PatientSubFolderField          matlab.ui.control.EditField
        PatientDatefolderField         matlab.ui.control.EditField
        PreviousButton_2               matlab.ui.control.Button
        NextButton_2                   matlab.ui.control.Button
        SliceEditFieldLabel            matlab.ui.control.Label
        SliceEditField                 matlab.ui.control.NumericEditField
        PreviousButton_3               matlab.ui.control.Button
        NextButton_3                   matlab.ui.control.Button
        PointcoeffEditFieldLabel       matlab.ui.control.Label
        PointcoeffEditField            matlab.ui.control.NumericEditField
        RefreshimageButton             matlab.ui.control.Button
    end

    
    properties (Access = public)  
        mainSavepath = ''; % folder containing the main path for saving
        rawdatapath = ''; %'C:\Users\Luca\Documents\SUS2020\'; % dicom folder
        workspacepath = ''; %'C:\Users\Luca\Desktop\SUS2020\Workspaces/'; % workspace folder
        patientspath = ''; %'C:\Users\Luca\Desktop\SUS2020\Parametric_Maps\'; % % patients folder
        modelspath = ''; %'C:\Users\Luca\OneDrive - Universitetet i Stavanger\PhD\Patients\SAVED_MODELS\'; % % models folder
        
        p_id = ""; % patient ID
        subfolds = ["MIP", "CBF", "CBV", "TMAX", "TTP", "MTT"]; 
        listofLoadSUBFold = ["/Original/", "/Annotations/"];
        loadingSubfold = "/Original/";
        listPatientFolders = [];
        currentPatientFolder_idx = 1;
        listPatientDateSubfolders = [];
        currentPatientDateSubf_idx = 1;
        infarctedRegions = [...
            struct("Name","penumbra", "Color", "cyan", "lastID", 0, "masks", struct(), "show", 1),...
            struct("Name", "core", "Color", "red", "lastID", 0, "masks", struct(), "show", 1)];
        infarcted_region_to_show = 'All';
        pm_to_show = "MIP";
        current_override_slice_n = 1;
    end
       
    methods (Access = private)
        
        % enable the save & move buttons
        function enableButtonsForMovingAndSaving(app, edit)
            % make the buttons editable / or not
            app.ListAnnotations.Enable = edit;
            % choose buttons 
            app.chooseRegion.Enable = edit;
            % save button
            app.SavecurrentworkButton.Enable = edit;
            app.DrawnewannotationButton.Enable = edit;
            % load different annotations buttons
            app.PreviousButton_4.Enable = edit;
            app.NextButton_4.Enable = edit;
            app.ConfirminadvanceCheckBox.Enable = edit;
            app.PointcoeffEditField.Enable = edit;
            app.PointcoeffEditField.Editable = edit;
            % patient panel
            app.PreviousButton.Enable = edit;
            app.PreviousButton_2.Enable = edit;
            app.PreviousButton_3.Enable = edit;
            app.NextButton.Enable = edit;
            app.NextButton_2.Enable = edit;
            app.NextButton_3.Enable = edit;
            app.PatientSubFolderField.Editable = edit;
            app.PatientSubFolderField.Enable = edit;
            app.PatientDatefolderField.Enable = edit;
            app.PatientDatefolderField.Enable = edit;
            app.SliceEditField.Editable = edit;
            app.SliceEditField.Enable = edit;
            app.SliceEditFieldLabel.Enable = edit;
                        
            % Parametric maps
            for pm = app.subfolds
                app.(pm).Visible = edit;
                app.(pm).Enable = edit;
                app.(strcat(pm,"Label")).Visible = edit;
                app.(strcat(pm,"Label")).Enable = edit;
            end
        end
        
        % function to set & populate the axes with the annotations and the
        % correct image
        function populateAxesWithImageAndAnnotations(app, overrideslice_n)
            
            app.PointcoeffEditField.Value = 0.5; % reset the coeffient value
            
            if nargin<2
                slice_n = fromNumToStr(app, app.SliceEditField.Value);
            else 
                slice_n = overrideslice_n;
            end
            
            for idx_reg = 1:numel(app.infarctedRegions)
                region = app.infarctedRegions(idx_reg).Name;
                color = app.infarctedRegions(idx_reg).Color;
                app.infarctedRegions(idx_reg).masks = struct(); % reset the list of annotations
                
                maskname = strcat(app.patientspath, app.listPatientFolders{app.currentPatientFolder_idx}, "/", ...
                        app.listPatientDateSubfolders{app.currentPatientDateSubf_idx}, app.loadingSubfold, ...
                        "superpixels2steps_tree_", app.p_id, "_", slice_n, "_", region, ".png");
                    
                if ~app.checkIfFileExist(maskname, "Load the annotations from the other folder!")
                    if strcmp(app.loadingSubfold, app.listofLoadSUBFold{1})
                        app.loadingSubfold = app.listofLoadSUBFold{2};
                        app.ManualButton.BackgroundColor = [0.39,0.83,0.07]; % green
                        app.AutomaticOriginalButton.BackgroundColor = [0.30,0.75,0.93]; % light-blue
                    elseif strcmp (app.loadingSubfold, app.listofLoadSUBFold{2})
                        app.loadingSubfold = app.listofLoadSUBFold{1};
                        app.AutomaticOriginalButton.BackgroundColor = [0.39,0.83,0.07]; % green
                        app.ManualButton.BackgroundColor = [0.30,0.75,0.93]; % light-blue
                    end
                    
                    % if the file doesn't exist, load the annotations with
                    % the other file
                    maskname = strcat(app.patientspath, app.listPatientFolders{app.currentPatientFolder_idx}, "/", ...
                        app.listPatientDateSubfolders{app.currentPatientDateSubf_idx}, app.loadingSubfold, ...
                        "superpixels2steps_tree_", app.p_id, "_", slice_n, "_", region, ".png");
                end
            
                M = contourc(double(imread(maskname)), 1);
                if ~isempty(M)
                    shapedata = contour2shape(M);
                    
                    for sx = 1:numel(shapedata)
                        label = strcat(region,"_",app.fromNumToStr(sx));
                        polyFromMask = [shapedata(sx).X', shapedata(sx).Y']; % coordinates (X,Y)
                        
                        newpoly = app.createNewStructAnnotation(polyFromMask,label,0);
                        
                        if numel(fieldnames(app.infarctedRegions(idx_reg).masks))==0
                            app.infarctedRegions(idx_reg).masks = newpoly;
                        else
                            app.infarctedRegions(idx_reg).masks = [app.infarctedRegions(idx_reg).masks newpoly];    
                        end
                    end
                    
                    app.infarctedRegions(idx_reg).lastID = sx; % update the last id field               
                end
            end
            
            drawPolygonFromMask(app);
            
        end
        
        % convert a integer to string with a zero in front (if needed)
        function results = fromNumToStr(~, value)
            results = num2str(value);
            if length(results) == 1
                results = strcat('0', results);
            end
        end
        
        % function that show an error dialog if the file is not found
        function results = checkIfFileExist(~, filename, addText)
            if nargin < 3
                addText = "";
            end
            
            results = true;
            if ~isfile(filename)                
                errordlg((filename + " does not exist." + newline + addText), "File not found.");
                results = false;
            end
        end
        
        % show the confirmation dialog
        function result = confirmMovingAndRedraw(app)
            result = false;
            
            % if the switch is set to "Yes" the confirm dialog is not shown
            if app.ConfirminadvanceCheckBox.Value==1
                result = true;
                return
            end
            
            selection = uiconfirm(app.GUIAutomaticManualAnnotationsUIFigure, "Every change not saved will be lost...", "Are you sure?");
            if strcmp(selection,"OK")
                result = true;
            end
        end
        
        % get the list of masks based on the label
        function mask = getMasksFromLabel(app, label)
            mask = struct();
            
            for infarctedReg = app.backupInfarctedRegions
                if strcmp(infarctedReg.Name,label)
                    for m = infarctedReg.masks
                        if numel(fieldnames(mask))==0
                            mask = m;
                        else
                            mask = [mask, m]; %#ok<*AGROW> 
                        end
                    end
                end
            end
        end
        
        % get the last ID based on the label
        function lastid = getLastIDFromLabel(app, label)
            lastid = 0;
            for infarctedReg = app.infarctedRegions
                if strcmp(infarctedReg.Name,label)
                    for m = infarctedReg.masks
                        if numel(fieldnames(m))>0
                            labelid = strsplit(m.ID, "_");
                            id = str2double(labelid(end));
                            if id > lastid
                                lastid = id;
                            end
                        end
                    end
                end
            end
        end
        
        % function to draw the polygons
        function drawPolygonFromMask(app)
            app.ListAnnotations.Items = {}; % reset list 
            slice_n = fromNumToStr(app, app.SliceEditField.Value);
            
            prevPos = app.annot.Position;
            cla(app.annot,'reset') % clear the axes
            
            % delete previous uiaxes from the panel (memory issue)!
            if numel(app.RightPanel.Children) > 1
                delete(app.RightPanel.Children(1));
            end
        
            app.annot = uiaxes(app.RightPanel);
            
            app.annot.Position = prevPos; 
            app.annot.XTick = [];
            app.annot.XTickLabel = [];
            app.annot.YTick = [];
            app.annot.YTickLabel = [];
            app.annot.Visible = 'off';
            app.annot.Color = 'none';
            app.annot.XColor = 'none';
            app.annot.YColor = 'none';
            app.annot.ZColor = 'none';
            
            filename = strcat(app.patientspath, app.listPatientFolders{app.currentPatientFolder_idx}, "/", ...
                        app.listPatientDateSubfolders{app.currentPatientDateSubf_idx}, "/", app.pm_to_show, "/", slice_n, ".png");
            
            if ~app.checkIfFileExist(filename)
                return
            end
                        
            imshow(filename, "Parent",app.annot, 'InitialMagnification','fit');
            
            for idx_ir = 1:numel(app.infarctedRegions)
                infarctedReg = app.infarctedRegions(idx_ir);
                
                if infarctedReg.show % draw only the regions with the show flag set to true
                    for idx_ms = 1:numel(infarctedReg.masks)
                        tolerance = 0.001; % number that reduce the points
            
                        maskStruct = infarctedReg.masks(idx_ms);
                        
                        if numel(fieldnames(maskStruct))>0
                            polyFromMask = maskStruct.mask;
                            
                            if ~(maskStruct.manually_drawn) % reduce/interpolate only if the annotation is not a manual draw
                                areaPoly = polyarea(polyFromMask(:,1),polyFromMask(:,2));
                                if areaPoly>0
                                    [~,convex] = convhull(polyFromMask);
                                    
                                    pointsTodrawMultiplier = (convex/areaPoly) * app.PointcoeffEditField.Value;
                                    pointsToDraw = ceil(maskStruct.n_points * pointsTodrawMultiplier);
                                    
                                    if pointsToDraw <= maskStruct.n_points % we need to reduce the points in the polygon
                                        polyFromMask_reduced = polyFromMask;
                                        
                                        while (length(polyFromMask_reduced) > pointsToDraw) && tolerance<1
                                            polyFromMask_reduced = reducepoly(polyFromMask,tolerance); % reduce the polygon points without tolerance 
                                            tolerance = tolerance+0.002;
                                        end
                                        polyFromMask = polyFromMask_reduced;
                                    else
                                        % otherwise, increase the number of points in
                                        % the polygon with interpolation 
                                        polyFromMask = interppolygon(polyFromMask,pointsToDraw);
                                    end
                                    
                                    % update mask and the main variable
                                    maskStruct.mask = polyFromMask;
                                    maskStruct.prev_tolerance = tolerance;  
                                    app.infarctedRegions(idx_ir).masks(idx_ms) = maskStruct;
                                end
                            end
                            
                            polymask = drawpolygon(app.annot,...
                                "Position",maskStruct.mask,...
                                "Color",infarctedReg.Color,...
                                "Tag",maskStruct.ID,...
                                "Label",maskStruct.ID,...
                                "LabelVisible","hover",...
                                "LineWidth",.1); % draw it    
                            
                            % update the variable when the ROI changed
                            addlistener(polymask, 'ROIMoved',  @(src,eventdata)app.updatePolygon(src,eventdata,idx_ir,idx_ms));
                            
                            app.ListAnnotations.Items = [app.ListAnnotations.Items, maskStruct.ID]; % append into the list annotations
                        end
                    end
                end
            end            
        end
        
        % delete the polygon based on the id
        function deletePolygon(app,id)
            for idx_reg = 1:numel(app.infarctedRegions)
                for idx_mask = 1:numel(app.infarctedRegions(idx_reg).masks)
                    if isfield(app.infarctedRegions(idx_reg).masks(idx_mask),"ID")
                        if strcmp(app.infarctedRegions(idx_reg).masks(idx_mask).ID, id)
                            app.infarctedRegions(idx_reg).masks(idx_mask) = [];
                            break
                        end
                    end
                end
            end
            
            app.DeleteselectedButton.Enable = 0;
        end
        
        % the struct for every mask
        function annot = createNewStructAnnotation(~, mask, id, flagManualDraw)
            annot = struct(...
                "mask",mask,...
                "original",mask,...
                "n_points",length(mask),...
                "prev_tolerance",0,...
                "manually_drawn",flagManualDraw,...
                "ID", id);
        end
        
        % callback for update the polygon when the ROI changes
        function updatePolygon(app,~,eventdata,idx_ir,idx_ms)
            app.infarctedRegions(idx_ir).masks(idx_ms).mask = eventdata.CurrentPosition; % update the infarcted region with the new position
        end
        
        % function that check if a list is empty or not (show an error
        % dialog if it is)
        function flag = checkIfListIsEmpty(~,listtocheck)
            flag = true;
            if isempty(listtocheck)
                flag = false;
                errordlg("Try to select again the main patient folder...", "There are no patients to display.");
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            wb = uiprogressdlg(app.GUIAutomaticManualAnnotationsUIFigure,'Title','Please Wait','Message','Start running...');
            if strcmp(app.patientspath,"")
                close(wb);
                f = msgbox('No patients folder selected', 'Error','error');
                return
            else 
                addpath(genpath(app.patientspath))
            end
            if strcmp(app.modelspath,"")
                close(wb);
                f = msgbox('No model folder selected', 'Error','error');
                return
            else
                addpath(genpath(app.modelspath))
            end
            
            close(wb);
            % run the annotations extraction
            prepareDataForExtract(app);
            
            % enable the other buttons
            enableButtonsForMovingAndSaving(app, 'on')
            
            firstPatient = 1;
            if app.RunallpatientsCheckBox.Value ~= 1
                firstPatient = app.FrompatientSpinner.Value;
            end
            
            if app.checkIfListIsEmpty(app.listPatientFolders)
                app.PatientSubFolderField.Value = app.listPatientFolders{app.FrompatientSpinner.Value};
                e.Value = app.PatientSubFolderField.Value;
                app.PatientSubFolderFieldValueChanged(e); % change the value of the patient
            end
        end

        % Button pushed function: BrowseButton, BrowseButton_4
        function BrowseButtonPushed(app, event)
            tmp = uigetdir(cd,"Select directory");
            figure(app.GUIAutomaticManualAnnotationsUIFigure)
            if ischar(tmp)
                app.mainSavepath = tmp;
                if ~strcmp(app.mainSavepath(end), "\") || ~strcmp(app.mainSavepath(end), "/") 
                    app.mainSavepath = strcat(app.mainSavepath, "\");
                end
                
                % create the various directories
                app.patientspath = strcat(app.mainSavepath,"Parametric_maps/");
                app.workspacepath = strcat(app.mainSavepath,"Workspace/");
                
                app.PatientFolderField.Value = app.patientspath;
                app.PatientFolderFieldValueChanged();
                app.savepatientField.Value = app.mainSavepath;
                app.savepatientFieldValueChanged();
            else
                f = msgbox('Something went wrong during the selection of the folder...', 'Error','error');
                return
            end
        end

        % Value changed function: SliceEditField
        function SliceEditFieldValueChanged(app, event)
            value = app.SliceEditField.Value;
            
            if sum(contains(fieldnames(event), 'PreviousValue'))==0 || app.confirmMovingAndRedraw()
                slice_n = fromNumToStr(app, value);
                
                for subfold = app.subfolds
                    filename = strcat(app.patientspath, app.listPatientFolders{app.currentPatientFolder_idx}, "/", ...
                        app.listPatientDateSubfolders{app.currentPatientDateSubf_idx}, "/", subfold, "/", slice_n, ".png");
                    
                    if ~app.checkIfFileExist(filename)
                        return
                    end
                    
                    app.(subfold).ImageSource = imread(filename);
                    app.(subfold).Tag = subfold;
                end
                
                % show all the infarcted region when you change slide
                app.infarcted_region_to_show = 'All'; 
                app.DeleteselectedButton.Enable = "off";
                % call the function to populate the axes
                populateAxesWithImageAndAnnotations(app)
            else
                app.SliceEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: RunallpatientsCheckBox
        function RunallpatientsCheckBoxValueChanged(app, event)
            value = app.RunallpatientsCheckBox.Value;
            edit = 'on';
            if value == 1
                edit = 'off';
            end
            
            app.FrompatientSpinner.Editable = edit;
            app.TopatientSpinner.Editable = edit;
            app.FrompatientSpinner.Enable = edit;
            app.TopatientSpinner.Enable = edit;
        end

        % Value changed function: chooseRegion
        function chooseRegionValueChanged(app, event)
            app.infarcted_region_to_show = app.chooseRegion.Value;
            
            for idx_reg = 1:numel(app.infarctedRegions)
                switch app.infarcted_region_to_show 
                    case 'All'
                        app.infarctedRegions(idx_reg).show = 1;
                    case {'Penumbra','Core'}
                        if strcmpi(app.infarctedRegions(idx_reg).Name,app.infarcted_region_to_show)
                            app.infarctedRegions(idx_reg).show = 1;
                        else
                            app.infarctedRegions(idx_reg).show = 0;
                        end
                end
            end
            
            app.DeleteselectedButton.Enable = "off";
            % call the function to populate the axes
            drawPolygonFromMask(app)
        end

        % Image clicked function: CBF, CBV, MIP, MTT, TMAX, TTP
        function ParametricMapImageClicked(app, event)
            app.pm_to_show = event.Source.Tag;
            app.DeleteselectedButton.Enable = "off";
            % call the function to populate the axes
            drawPolygonFromMask(app)
        end

        % Value changed function: ListAnnotations
        function ListAnnotationsValueChanged(app, event)
            value = app.ListAnnotations.Value;
            
            if ~isempty(value)
                app.DeleteselectedButton.Enable = "on";
            else 
                app.DeleteselectedButton.Enable = "off";
            end
        end

        % Button pushed function: DeleteselectedButton
        function DeleteselectedButtonPushed(app, event)
            if isempty(app.ListAnnotations.Value)
                errordlg("Nothing select in the list.","Error");
                return
            end
            annot_label = convertCharsToStrings(app.ListAnnotations.Value);
            
            if app.confirmMovingAndRedraw()
                selection = "OK";
            else
                selection = uiconfirm(app.GUIAutomaticManualAnnotationsUIFigure, strcat("Delete ", annot_label, " annotation?"),'Delete selected annotation?','Icon','warning');
            end
            
            if strcmp(selection,"OK")
               deletePolygon(app,annot_label); % delete the polygon 
               drawPolygonFromMask(app); % and draw again
            end
        end

        % Button pushed function: DrawnewannotationButton
        function DrawnewannotationButtonPushed(app, event)
            selection = uiconfirm(app.GUIAutomaticManualAnnotationsUIFigure, "Draw a new annotation?",'Draw annotation',"Options",["Draw Penumbra", "Draw Core", "Cancel"]);
            id = ""; label = ""; 
            
            if strcmp(selection,"Draw Penumbra")
                label = "penumbra";
            elseif strcmp(selection,"Draw Core")
                label = "core";
            else
                return
            end
            
            id = strcat(label, "_", app.fromNumToStr(app.getLastIDFromLabel(label)+1));
            
            polymask = drawpolygon(app.annot,...
                "LabelVisible","hover",...
                "LineWidth",.1); % draw it    
            
            newpoly = app.createNewStructAnnotation(polymask.Position,id,1);
            
            for idx_reg = 1:numel(app.infarctedRegions)
                if strcmp(app.infarctedRegions(idx_reg).Name,label)
                    if numel(fieldnames(app.infarctedRegions(idx_reg).masks))==0
                        app.infarctedRegions(idx_reg).masks = newpoly;
                    else
                        app.infarctedRegions(idx_reg).masks = [app.infarctedRegions(idx_reg).masks newpoly];    
                    end        
                end
            end
            
            app.drawPolygonFromMask(); % redraw the annotations
            
        end

        % Button pushed function: SavecurrentworkButton
        function SavecurrentworkButtonPushed(app, event)
            if app.ConfirminadvanceCheckBox.Value==1
                selection = "OK";
            else
                selection = uiconfirm(app.GUIAutomaticManualAnnotationsUIFigure, "Are your sure? It will override the saved mask(s)...", "Save the current work?");
            end
            
            if strcmp(selection,"OK")
                pause on % to enable pause function
                wb = uiprogressdlg(app.GUIAutomaticManualAnnotationsUIFigure,'Title','Please Wait','Message','Saving...');
                
                slice_n = fromNumToStr(app, app.SliceEditField.Value);
            
                for idx_reg = 1:numel(app.infarctedRegions)
                    region = app.infarctedRegions(idx_reg).Name;
                    
                    wb.Value = idx_reg/numel(app.infarctedRegions);
                    wb.Message = strcat("Saving ", region, " annotation(s)...");
                    pause(0.5);
                    
                    originalfilename = strcat(app.patientspath, app.listPatientFolders{app.currentPatientFolder_idx}, "/", ...
                        app.listPatientDateSubfolders{app.currentPatientDateSubf_idx}, app.listofLoadSUBFold{1}, "superpixels2steps_tree_", app.p_id, "_", slice_n, "_", region, ".png");
                    
                    image = imread(originalfilename);
                    BW = zeros(size(image,1),"double");
                    for m = app.infarctedRegions(idx_reg).masks
                        if isfield(m,"mask")
                            BW = BW + poly2mask(m.mask(:,1),m.mask(:,2), size(image,1), size(image,2));
                        end
                    end
                    
                    filename = strcat(app.patientspath, app.listPatientFolders{app.currentPatientFolder_idx}, "/", ...
                        app.listPatientDateSubfolders{app.currentPatientDateSubf_idx}, app.listofLoadSUBFold{2}, "superpixels2steps_tree_", app.p_id, "_", slice_n, "_", region, ".png");
                    
                    imwrite(BW,filename);
                end
                
                close(wb);
                pause off;
            end
        end

        % Value changed function: PatientFolderField
        function PatientFolderFieldValueChanged(app, event)
            app.patientspath = app.PatientFolderField.Value;
            
            % get the list of patient folders 
            for p=dir(app.patientspath)'
                if ~strcmp(p.name, '.') && ~strcmp(p.name, '..')
                    app.listPatientFolders = [app.listPatientFolders; convertCharsToStrings(p.name)];
                end                
            end
            
            if app.checkIfListIsEmpty(app.listPatientFolders)
                app.TopatientSpinner.Limits = [1,length(app.listPatientFolders)]; % set the limit to the spinner
                                
                app.PatientSubFolderField.Value = app.listPatientFolders{app.FrompatientSpinner.Value};
                e.Value = app.PatientSubFolderField.Value;
                e.Dontshow = true;
                app.PatientSubFolderFieldValueChanged(e);
            end
        end

        % Value changed function: ModelFolderField
        function ModelFolderFieldValueChanged(app, event)
            app.modelspath = app.ModelFolderField.Value;
        end

        % Button pushed function: PreviousButton_4
        function PreviousButton_4Pushed(app, event)
            if app.confirmMovingAndRedraw()
                app.DeleteselectedButton.Enable = "off";
                overrideslice_n = fromNumToStr(app, app.SliceEditField.Value-app.current_override_slice_n);
                app.current_override_slice_n = app.current_override_slice_n - 1;
                
                populateAxesWithImageAndAnnotations(app, overrideslice_n)
            end
        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
            if app.confirmMovingAndRedraw()
                app.DeleteselectedButton.Enable = "off";
                overrideslice_n = fromNumToStr(app, app.SliceEditField.Value+app.current_override_slice_n);
                app.current_override_slice_n = app.current_override_slice_n + 1;
                
                populateAxesWithImageAndAnnotations(app, overrideslice_n)
            end
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            tmp = uigetdir(cd,"Select DICOM directory");
            figure(app.GUIAutomaticManualAnnotationsUIFigure)
            if ischar(tmp)
                app.rawdatapath = tmp;
                if ~strcmp(app.rawdatapath(end), "\") || ~strcmp(app.rawdatapath(end), "/") 
                    app.rawdatapath = strcat(app.rawdatapath, "\");
                end
                app.RawdataField.Value = app.rawdatapath;
                app.RawdataFieldValueChanged();
            end
        end

        % Value changed function: RawdataField
        function RawdataFieldValueChanged(app, event)
            app.rawdatapath = app.RawdataField.Value;
            
            if ~strcmp(app.rawdatapath,"") && ~strcmp(app.mainSavepath,"") 
                app.ExtractParametricMapsButton.Enable = true;
            else
                app.ExtractParametricMapsButton.Enable = false;
            end
        end

        % Value changed function: savepatientField
        function savepatientFieldValueChanged(app, event)
            app.mainSavepath = app.savepatientField.Value;
            
            if ~strcmp(app.rawdatapath,"") && ~strcmp(app.mainSavepath,"") 
                app.ExtractParametricMapsButton.Enable = true;
            else
                app.ExtractParametricMapsButton.Enable = false;
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_5Pushed(app, event)
            tmp = uigetdir(cd,"Select model directory");
            figure(app.GUIAutomaticManualAnnotationsUIFigure)
            if ischar(tmp)
                app.modelspath = tmp;
                if ~strcmp(app.modelspath(end), "\") || ~strcmp(app.modelspath(end), "/") 
                    app.modelspath = strcat(app.modelspath, "\");
                end
                app.ModelFolderField.Value = app.modelspath;
            end
        end

        % Callback function
        function workspaceFieldValueChanged(app, event)
            app.workspacepath = app.workspaceField.Value;
        end

        % Button pushed function: ExtractParametricMapsButton
        function ExtractParametricMapsButtonPushed(app, event)
            wb = uiprogressdlg(app.GUIAutomaticManualAnnotationsUIFigure,'Title','Please Wait','Message','Start running...');
            if strcmp(app.rawdatapath,"")
                close(wb);
                f = msgbox('No DICOM folder selected', 'Error','error');
                return
            else 
                addpath(genpath(app.rawdatapath))
            end
            if strcmp(app.patientspath,"")
                close(wb);
                f = msgbox('No save folder selected', 'Error','error');
                return
            else 
                addpath(genpath(app.patientspath))
            end
            if strcmp(app.workspacepath,"")
                close(wb);
                f = msgbox('No workspace folder selected', 'Error','error');
                return
            else
                addpath(genpath(app.workspacepath))
            end
           
            close(wb);
           
            % create the subfolders 
            mkdir(app.patientspath)
            mkdir(app.workspacepath)
            generateParametricMaps(app);
        end

        % Callback function: PointcoeffEditField, RefreshimageButton
        function CoefficientforpointsValueChanged(app, event)
            app.DeleteselectedButton.Enable = "off";
            app.drawPolygonFromMask(); % redraw the annotations
        end

        % Button pushed function: AutomaticOriginalButton
        function AutomaticOriginalButtonPushed(app, event)
            if confirmMovingAndRedraw(app)
                if app.checkIfListIsEmpty(app.listPatientFolders)
                    app.DeleteselectedButton.Enable = "off";
                    
                    % enable the other buttons
                    enableButtonsForMovingAndSaving(app, 'on')
                    
                    app.loadingSubfold = app.listofLoadSUBFold{1};
                    app.AutomaticOriginalButton.BackgroundColor = [0.39,0.83,0.07]; % green
                    app.ManualButton.BackgroundColor = [0.30,0.75,0.93]; % light-blue
                    
                    e.Value = app.listPatientFolders{app.currentPatientFolder_idx};
                    PatientSubFolderFieldValueChanged(app,e); % change the value of the patient
                end
            end
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            if confirmMovingAndRedraw(app)
                if app.checkIfListIsEmpty(app.listPatientFolders)
                    app.DeleteselectedButton.Enable = "off";
                    
                    % enable the other buttons
                    enableButtonsForMovingAndSaving(app, 'on')
                    
                    app.loadingSubfold = app.listofLoadSUBFold{2};
                    app.ManualButton.BackgroundColor = [0.39,0.83,0.07]; % green
                    app.AutomaticOriginalButton.BackgroundColor = [0.30,0.75,0.93]; % light-blue
                    
                    e.Value = app.listPatientFolders{app.currentPatientFolder_idx};
                    PatientSubFolderFieldValueChanged(app,e); % change the value of the patient
                end
            end
        end

        % Value changed function: PatientSubFolderField
        function PatientSubFolderFieldValueChanged(app, event)
            app.PatientSubFolderField.Value = event.Value;
            value = app.PatientSubFolderField.Value;
                        
            app.listPatientDateSubfolders = []; % reset the list and the index
            app.currentPatientFolder_idx = find(app.listPatientFolders==value); % index of the current patient
            app.currentPatientDateSubf_idx = 1;
            
            for date = dir(strcat(app.patientspath,'/',value))'
                if ~strcmp(date.name, '.') && ~strcmp(date.name, '..')
                    app.listPatientDateSubfolders = [app.listPatientDateSubfolders; convertCharsToStrings(date.name)];
                end
            end
           
            app.PatientDatefolderField.Value = app.listPatientDateSubfolders{app.currentPatientDateSubf_idx};
            
            if sum(contains(fieldnames(event), 'Dontshow'))==0
                e.Value = app.PatientDatefolderField.Value;
                app.PatientDatefolderFieldValueChanged(e);         
            end
        end

        % Value changed function: PatientDatefolderField
        function PatientDatefolderFieldValueChanged(app, event)
            app.PatientDatefolderField.Value = event.Value;
            value = app.PatientDatefolderField.Value;
            
            app.p_id = getIndexFromPatient(app.PatientSubFolderField.Value, app.currentPatientDateSubf_idx);
           
            e.Value = app.SliceEditField.Value;
            SliceEditFieldValueChanged(app,e); % change the value of the slice 
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.confirmMovingAndRedraw()
                app.currentPatientFolder_idx = app.currentPatientFolder_idx + 1;
                if app.currentPatientFolder_idx>0 && app.currentPatientFolder_idx<=length(app.listPatientFolders)
                    e.Value = app.listPatientFolders{app.currentPatientFolder_idx};
                    app.PatientSubFolderFieldValueChanged(e);
                else
                    app.currentPatientFolder_idx = app.currentPatientFolder_idx - 1;
                    errordlg("No patient folder found.");
                end
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.confirmMovingAndRedraw()
                app.currentPatientFolder_idx = app.currentPatientFolder_idx - 1;
                if app.currentPatientFolder_idx>0 && app.currentPatientFolder_idx <= length(app.listPatientFolders)
                    e.Value = app.listPatientFolders{app.currentPatientFolder_idx};
                    app.PatientSubFolderFieldValueChanged(e);
                else
                    app.currentPatientFolder_idx = app.currentPatientFolder_idx + 1;
                    errordlg("No patient folder found.");
                end
            end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            if app.confirmMovingAndRedraw()
                app.currentPatientDateSubf_idx = app.currentPatientDateSubf_idx + 1;
                if app.currentPatientDateSubf_idx > 0 && app.currentPatientDateSubf_idx <= length(app.listPatientDateSubfolders)
                    e.Value = app.listPatientDateSubfolders{app.currentPatientDateSubf_idx};
                    app.PatientDatefolderFieldValueChanged(e);
                else
                    app.currentPatientDateSubf_idx = app.currentPatientDateSubf_idx - 1;
                    errordlg("No date folder found.");
                end
            end
        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            if app.confirmMovingAndRedraw()
                app.currentPatientDateSubf_idx = app.currentPatientDateSubf_idx - 1;
                if app.currentPatientDateSubf_idx > 0 && app.currentPatientDateSubf_idx <= length(app.listPatientDateSubfolders)
                    e.Value = app.listPatientDateSubfolders{app.currentPatientDateSubf_idx };
                    app.PatientDatefolderFieldValueChanged(e);
                else
                    app.currentPatientDateSubf_idx = app.currentPatientDateSubf_idx + 1;
                    errordlg("No date folder found.");
                end
            end
        end

        % Button pushed function: FinalizeParametricMapsButton
        function FinalizeParametricMapsButtonPushed(app, event)
            if strcmp(app.mainSavepath,'')
                errordlg("No MAIN save folder found.", "Error");
                return
            end
            
            finalizeParametricMaps(app);
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            if app.confirmMovingAndRedraw()
                app.SliceEditField.Value = app.SliceEditField.Value + 1;
                e.Value = app.SliceEditField.Value;
                app.SliceEditFieldValueChanged(e);
            end
        end

        % Button pushed function: PreviousButton_3
        function PreviousButton_3Pushed(app, event)
            if app.confirmMovingAndRedraw() && app.SliceEditField.Value > 1
                app.SliceEditField.Value = app.SliceEditField.Value - 1;
                e.Value = app.SliceEditField.Value;
                app.SliceEditFieldValueChanged(e);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIAutomaticManualAnnotationsUIFigure = uifigure('Visible', 'off');
            app.GUIAutomaticManualAnnotationsUIFigure.Name = 'GUI Automatic-Manual Annotations';
            app.RightPanel = uipanel(app.GUIAutomaticManualAnnotationsUIFigure);
            app.GridLayout6 = uigridlayout(app.RightPanel);
            app.annot = uiaxes(app.GridLayout6);
            title(app.annot, '')
            xlabel(app.annot, '')
            ylabel(app.annot, '')
            app.annot.XTick = [];
            app.annot.XTickLabel = '';
            app.annot.YTick = [];
            app.ParametricmapsPanel = uipanel(app.GUIAutomaticManualAnnotationsUIFigure);
            app.ParametricmapsPanel.Title = 'Parametric maps';
            app.GridLayout5 = uigridlayout(app.ParametricmapsPanel);
            app.CBF = uiimage(app.GridLayout5);
            app.CBF.ImageClickedFcn = createCallbackFcn(app, @ParametricMapImageClicked, true);
            app.CBF.Enable = 'off';
            app.CBF.Visible = 'off';
            app.CBV = uiimage(app.GridLayout5);
            app.CBV.ImageClickedFcn = createCallbackFcn(app, @ParametricMapImageClicked, true);
            app.CBV.Enable = 'off';
            app.CBV.Visible = 'off';
            app.CBFLabel = uilabel(app.GridLayout5);
            app.CBFLabel.Enable = 'off';
            app.CBFLabel.Visible = 'off';
            app.CBFLabel.Text = {'CBF'; ''};
            app.CBVLabel = uilabel(app.GridLayout5);
            app.CBVLabel.Enable = 'off';
            app.CBVLabel.Visible = 'off';
            app.CBVLabel.Text = {'CBV'; ''};
            app.TTP = uiimage(app.GridLayout5);
            app.TTP.ImageClickedFcn = createCallbackFcn(app, @ParametricMapImageClicked, true);
            app.TTP.Enable = 'off';
            app.TTP.Visible = 'off';
            app.TTPLabel = uilabel(app.GridLayout5);
            app.TTPLabel.Enable = 'off';
            app.TTPLabel.Visible = 'off';
            app.TTPLabel.Text = 'TTP';
            app.TMAX = uiimage(app.GridLayout5);
            app.TMAX.ImageClickedFcn = createCallbackFcn(app, @ParametricMapImageClicked, true);
            app.TMAX.Enable = 'off';
            app.TMAX.Visible = 'off';
            app.MIP = uiimage(app.GridLayout5);
            app.MIP.ImageClickedFcn = createCallbackFcn(app, @ParametricMapImageClicked, true);
            app.MIP.Enable = 'off';
            app.MIP.Visible = 'off';
            app.MIPLabel = uilabel(app.GridLayout5);
            app.MIPLabel.Enable = 'off';
            app.MIPLabel.Visible = 'off';
            app.MIPLabel.Text = 'MIP';
            app.TMAXLabel = uilabel(app.GridLayout5);
            app.TMAXLabel.Enable = 'off';
            app.TMAXLabel.Visible = 'off';
            app.TMAXLabel.Text = 'TMAX';
            app.MTT = uiimage(app.GridLayout5);
            app.MTT.ImageClickedFcn = createCallbackFcn(app, @ParametricMapImageClicked, true);
            app.MTT.Enable = 'off';
            app.MTT.Visible = 'off';
            app.MTTLabel = uilabel(app.GridLayout5);
            app.MTTLabel.Enable = 'off';
            app.MTTLabel.Visible = 'off';
            app.MTTLabel.Text = 'MTT';
            app.TabGroup = uitabgroup(app.GUIAutomaticManualAnnotationsUIFigure);
            app.ParametricmapsTab = uitab(app.TabGroup);
            app.ParametricmapsTab.Title = 'Parametric maps';
            app.GridLayout14 = uigridlayout(app.ParametricmapsTab);
            app.DICOMimagesmainfolderPanel = uipanel(app.GridLayout14);
            app.DICOMimagesmainfolderPanel.Title = 'DICOM images main folder ';
            app.GridLayout13 = uigridlayout(app.DICOMimagesmainfolderPanel);
            app.RawdataField = uieditfield(app.GridLayout13, 'text');
            app.RawdataField.ValueChangedFcn = createCallbackFcn(app, @RawdataFieldValueChanged, true);
            app.BrowseButton_3 = uibutton(app.GridLayout13, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.MainSavefolderPanel_2 = uipanel(app.GridLayout14);
            app.MainSavefolderPanel_2.Title = 'Main Save folder';
            app.GridLayout13_2 = uigridlayout(app.MainSavefolderPanel_2);
            app.savepatientField = uieditfield(app.GridLayout13_2, 'text');
            app.savepatientField.ValueChangedFcn = createCallbackFcn(app, @savepatientFieldValueChanged, true);
            app.BrowseButton_4 = uibutton(app.GridLayout13_2, 'push');
            app.BrowseButton_4.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton_4.Text = 'Browse';
            app.ExtractParametricMapsButton = uibutton(app.GridLayout14, 'push');
            app.ExtractParametricMapsButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractParametricMapsButtonPushed, true);
            app.ExtractParametricMapsButton.Enable = 'off';
            app.ExtractParametricMapsButton.Text = 'Extract Parametric Maps';
            app.PredictionsTab = uitab(app.TabGroup);
            app.PredictionsTab.Title = 'Predictions';
            app.GridLayout7 = uigridlayout(app.PredictionsTab);
            app.RunallpatientsCheckBox = uicheckbox(app.GridLayout7);
            app.RunallpatientsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunallpatientsCheckBoxValueChanged, true);
            app.RunallpatientsCheckBox.Text = 'Run all patients';
            app.RunButton = uibutton(app.GridLayout7, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FrompatientSpinnerLabel = uilabel(app.GridLayout7);
            app.FrompatientSpinnerLabel.Text = 'From patient';
            app.FrompatientSpinner = uispinner(app.GridLayout7);
            app.FrompatientSpinner.Limits = [1 Inf];
            app.FrompatientSpinner.Value = 1;
            app.TopatientSpinnerLabel = uilabel(app.GridLayout7);
            app.TopatientSpinnerLabel.Text = 'To patient';
            app.TopatientSpinner = uispinner(app.GridLayout7);
            app.TopatientSpinner.Limits = [1 Inf];
            app.TopatientSpinner.Value = 1;
            app.MainSavefolderPanel = uipanel(app.GridLayout7);
            app.MainSavefolderPanel.Title = 'Main Save folder';
            app.GridLayout9 = uigridlayout(app.MainSavefolderPanel);
            app.PatientFolderField = uieditfield(app.GridLayout9, 'text');
            app.PatientFolderField.ValueChangedFcn = createCallbackFcn(app, @PatientFolderFieldValueChanged, true);
            app.BrowseButton = uibutton(app.GridLayout9, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.ModelFolderPanel = uipanel(app.GridLayout7);
            app.ModelFolderPanel.Title = 'Model Folder';
            app.GridLayout10 = uigridlayout(app.ModelFolderPanel);
            app.ModelFolderField = uieditfield(app.GridLayout10, 'text');
            app.ModelFolderField.ValueChangedFcn = createCallbackFcn(app, @ModelFolderFieldValueChanged, true);
            app.BrowseButton_2 = uibutton(app.GridLayout10, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_5Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.FinalizeParametricMapsButton = uibutton(app.GridLayout7, 'push');
            app.FinalizeParametricMapsButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeParametricMapsButtonPushed, true);
            app.FinalizeParametricMapsButton.Text = {'Finalize Parametric Maps'; ''};
            app.DrawTab = uitab(app.TabGroup);
            app.DrawTab.Title = 'Draw';
            app.GridLayout8 = uigridlayout(app.DrawTab);
            app.SavecurrentworkButton = uibutton(app.GridLayout8, 'push');
            app.SavecurrentworkButton.ButtonPushedFcn = createCallbackFcn(app, @SavecurrentworkButtonPushed, true);
            app.SavecurrentworkButton.Enable = 'off';
            app.SavecurrentworkButton.Text = 'Save current work';
            app.DrawnewannotationButton = uibutton(app.GridLayout8, 'push');
            app.DrawnewannotationButton.ButtonPushedFcn = createCallbackFcn(app, @DrawnewannotationButtonPushed, true);
            app.DrawnewannotationButton.Enable = 'off';
            app.DrawnewannotationButton.Text = 'Draw new annotation';
            app.DeleteselectedButton = uibutton(app.GridLayout8, 'push');
            app.DeleteselectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedButtonPushed, true);
            app.DeleteselectedButton.Enable = 'off';
            app.DeleteselectedButton.Text = 'Delete selected';
            app.ListAnnotations = uilistbox(app.GridLayout8);
            app.ListAnnotations.Items = {};
            app.ListAnnotations.ValueChangedFcn = createCallbackFcn(app, @ListAnnotationsValueChanged, true);
            app.ListAnnotations.Enable = 'off';
            app.ListAnnotations.Value = {};
            app.chooseRegion = uidropdown(app.GridLayout8);
            app.chooseRegion.Items = {'All', 'Penumbra', 'Core'};
            app.chooseRegion.ValueChangedFcn = createCallbackFcn(app, @chooseRegionValueChanged, true);
            app.chooseRegion.Enable = 'off';
            app.chooseRegion.Value = 'All';
            app.VisualizeannotationsLabel = uilabel(app.GridLayout8);
            app.VisualizeannotationsLabel.Text = 'Visualize annotations:';
            app.LoadannotationsfromslicePanel = uipanel(app.GridLayout8);
            app.LoadannotationsfromslicePanel.Title = 'Load annotations from slice:';
            app.GridLayout12 = uigridlayout(app.LoadannotationsfromslicePanel);
            app.PreviousButton_4 = uibutton(app.GridLayout12, 'push');
            app.PreviousButton_4.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_4Pushed, true);
            app.PreviousButton_4.Enable = 'off';
            app.PreviousButton_4.Text = 'Previous';
            app.NextButton_4 = uibutton(app.GridLayout12, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Enable = 'off';
            app.NextButton_4.Text = 'Next ';
            app.ConfirminadvanceCheckBox = uicheckbox(app.GridLayout8);
            app.ConfirminadvanceCheckBox.Text = 'Confirm in advance?';
            app.LoadannotationsPanel = uipanel(app.GridLayout8);
            app.LoadannotationsPanel.Title = 'Load annotations';
            app.GridLayout15 = uigridlayout(app.LoadannotationsPanel);
            app.AutomaticOriginalButton = uibutton(app.GridLayout15, 'push');
            app.AutomaticOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticOriginalButtonPushed, true);
            app.AutomaticOriginalButton.Text = 'Automatic/Original';
            app.ManualButton = uibutton(app.GridLayout15, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.PatientfolderinfoPanel = uipanel(app.GridLayout8);
            app.PatientfolderinfoPanel.Title = 'Patient folder info';
            app.GridLayout16 = uigridlayout(app.PatientfolderinfoPanel);
            app.PreviousButton = uibutton(app.GridLayout16, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Enable = 'off';
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.GridLayout16, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.PatientSubFolderField = uieditfield(app.GridLayout16, 'text');
            app.PatientSubFolderField.ValueChangedFcn = createCallbackFcn(app, @PatientSubFolderFieldValueChanged, true);
            app.PatientSubFolderField.Editable = 'off';
            app.PatientSubFolderField.Enable = 'off';
            app.PatientDatefolderField = uieditfield(app.GridLayout16, 'text');
            app.PatientDatefolderField.ValueChangedFcn = createCallbackFcn(app, @PatientDatefolderFieldValueChanged, true);
            app.PatientDatefolderField.Editable = 'off';
            app.PatientDatefolderField.Enable = 'off';
            app.PreviousButton_2 = uibutton(app.GridLayout16, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Enable = 'off';
            app.PreviousButton_2.Text = {'Previous'; ''};
            app.NextButton_2 = uibutton(app.GridLayout16, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Enable = 'off';
            app.NextButton_2.Text = 'Next';
            app.SliceEditFieldLabel = uilabel(app.GridLayout16);
            app.SliceEditFieldLabel.Enable = 'off';
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SliceEditField = uieditfield(app.GridLayout16, 'numeric');
            app.SliceEditField.Limits = [1 Inf];
            app.SliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceEditFieldValueChanged, true);
            app.SliceEditField.Editable = 'off';
            app.SliceEditField.Enable = 'off';
            app.SliceEditField.Value = 1;
            app.PreviousButton_3 = uibutton(app.GridLayout16, 'push');
            app.PreviousButton_3.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_3Pushed, true);
            app.PreviousButton_3.Enable = 'off';
            app.PreviousButton_3.Text = 'Previous';
            app.NextButton_3 = uibutton(app.GridLayout16, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Enable = 'off';
            app.NextButton_3.Text = 'Next';
            app.PointcoeffEditFieldLabel = uilabel(app.GridLayout8);
            app.PointcoeffEditFieldLabel.Text = 'Point coeff:';
            app.PointcoeffEditField = uieditfield(app.GridLayout8, 'numeric');
            app.PointcoeffEditField.Limits = [0.01 1];
            app.PointcoeffEditField.ValueChangedFcn = createCallbackFcn(app, @CoefficientforpointsValueChanged, true);
            app.PointcoeffEditField.Editable = 'off';
            app.PointcoeffEditField.Enable = 'off';
            app.PointcoeffEditField.Value = 0.5;
            app.RefreshimageButton = uibutton(app.GridLayout8, 'push');
            app.RefreshimageButton.ButtonPushedFcn = createCallbackFcn(app, @CoefficientforpointsValueChanged, true);
            app.RefreshimageButton.Text = 'Refresh image';
            % Show the figure after all components are created
            app.GUIAutomaticManualAnnotationsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_annotations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIAutomaticManualAnnotationsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIAutomaticManualAnnotationsUIFigure)
        end
    end
end